classdef TDCgui3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                          matlab.ui.Figure
        RedPitayaIPAddressLabel      matlab.ui.control.Label
        RPaddr                       matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        Port                         matlab.ui.control.NumericEditField
        ConnectButton                matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        CalibrationTab               matlab.ui.container.Tab
        ChannelselectionButtonGroup  matlab.ui.container.ButtonGroup
        TDC0Button                   matlab.ui.control.RadioButton
        TDC1Button                   matlab.ui.control.RadioButton
        RUNButton                    matlab.ui.control.StateButton
        TDCLabel                     matlab.ui.control.Label
        CalibrationInfoLabel         matlab.ui.control.Label
        EnableButton                 matlab.ui.control.StateButton
        EnableLamp                   matlab.ui.control.Lamp
        CalibrateButton              matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        CountsLabel                  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        PlotcalibrationdataButton    matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
        ExportButton_2               matlab.ui.control.Button
        HistogramdataLabel           matlab.ui.control.Label
        MeasurementTab               matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        DropDownSTART                matlab.ui.control.DropDown
        STARTLabel                   matlab.ui.control.Label
        STOPLabel                    matlab.ui.control.Label
        DropDownSTOP                 matlab.ui.control.DropDown
        RUNButton2                   matlab.ui.control.StateButton
        CountsMLabel                 matlab.ui.control.Label
        OffsetLabel                  matlab.ui.control.Label
        ZeroButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        PeakstatisticsButton         matlab.ui.control.Button
        ClearButton2                 matlab.ui.control.Button
        StatsLabel                   matlab.ui.control.Label
        TriggerwindowLabel           matlab.ui.control.Label
        windowLowField               matlab.ui.control.NumericEditField
        ScaleSwitch                  matlab.ui.control.Switch
        ExportButton                 matlab.ui.control.Button
        FromLabel                    matlab.ui.control.Label
        psLabel                      matlab.ui.control.Label
        ToEditFieldLabel             matlab.ui.control.Label
        windowHighField              matlab.ui.control.NumericEditField
        psLabel_2                    matlab.ui.control.Label
        FirstSTOPCheckBox            matlab.ui.control.CheckBox
        SpeedtestTab                 matlab.ui.container.Tab
        TimestampcollectionbenchmarkLabel  matlab.ui.control.Label
        ULabel                       matlab.ui.control.Label
        ARMCprogramLabel             matlab.ui.control.Label
        Benchmark1Button             matlab.ui.control.StateButton
        Benchmark1Label              matlab.ui.control.Label
        MatlabGUILabel               matlab.ui.control.Label
        Benchmark2Button             matlab.ui.control.StateButton
        Benchmark2Label              matlab.ui.control.Label
        SystemTempLabel              matlab.ui.control.Label
        tempGauge                    matlab.ui.control.NinetyDegreeGauge
        tempLabel                    matlab.ui.control.Label
    end


    properties (Access = private)
        
        connected = 0; % connection status
        client; % TCP client object
        
        Nch = 2; % No. of TDC channels
        Nbins = 192; % No. of delay line taps
        T = 10^6 / 350; % clock period in ps (f = 350 MHz)
        BRAMsize = 2048;
        
        % ---------------CALIBRATION---------------------------------------
        
        TDCenabled; % TDC channel enabled array (1 x Nch)
        selectedTDC = 0; % displayed TDC channel
        
        counts; % number of counts in each channel (1 x Nch)
        histogramData; % histogram values for each channel (Nch x Nbins)
        
        calibrationPts; % number of points used for calibration (1 x Nch)
        binWidths; % bin widths in ps for each channel
        calibrationTable; % bin No. to ps LUT
        activeTaps; % No. of active taps (1 x Nch)
        avgWidth; % average bin width in ps (1 x Nch)
        
        DNL; % differential nonlinearity (Nch x Nbins)
        INL; % integral nonlinearity (Nch x Nbins)
        
        % ----------------MEASUREMENT------------------------------------------
        
        startTDC = 0; % START channel index
        stopTDC = 0; % STOP channel index
        
        TDCmeasurements; % measured time differences in ps (Nch x Nch x 500*BRAMsize) ... million points deep
        Nmeasurements; % number of points matrix (Nch x Nch)
        offset; % channel offset matrix (Nch x Nch); zero diagonal elements
        
        windowHigh; % trigger window
        windowLow;
        
        % ----------------TEMPETARUTE MONITORING-------------------------------
        
        xadcTimer; % timer object for system monitoring
        timerFlag; % triggered timer flag
        
        % -----------------BENCHMARKING----------------------------------------
        
        benchmarkTimer1; % timer object for the 10 second speedtest (1)
        benchmarkTimer2; % timer object for the 10 second speedtest (2)
        
        % -----------------FILE------------------------------------------------
        fileDirectory = 'C:\Users\Michel\Desktop\MATLABexports';  % folder for export/import operations
        fileIndex; % counter for writing histograms to files
        
    end

    methods (Access = private)
    
        function activeFields(app) % manages which fields are enabled
            
            % RUN button, Enable button, Clear button (CALIBRATION tab)
            if (app.connected == 0) || (app.RUNButton2.Value == 1) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.RUNButton.Enable = 'off';
                app.EnableButton.Enable = 'off';
                app.ClearButton.Enable = 'off';
            else
                app.RUNButton.Enable = 'on';
                app.EnableButton.Enable = 'on';
                app.ClearButton.Enable = 'on';
            end
            
            % RUN2 button (MEASUREMENT tab)
            allDisabled = 1;
            for channel = 1 : length(app.TDCenabled) % check if all channels are disabled
                if app.TDCenabled(channel) == 1
                    allDisabled = 0;
                    break
                end
            end
            if (app.connected == 0) || (app.RUNButton.Value == 1) || (allDisabled == 0) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.RUNButton2.Enable = 'off';
            else
                app.RUNButton2.Enable = 'on';
            end
            
            % Measurement offset stuff, 1stSTOP checkbox (MEASUREMENT tab)
            if app.startTDC == app.stopTDC
                app.OffsetLabel.Enable = 'off';
                app.ZeroButton.Enable = 'off';
                app.ResetButton.Enable = 'off';
                app.FirstSTOPCheckBox.Enable = 'off';
            else
                app.OffsetLabel.Enable = 'on';
                app.ZeroButton.Enable = 'on';
                app.ResetButton.Enable = 'on';
                app.FirstSTOPCheckBox.Enable = 'on';
            end
            
            % Dropdown channel selection (MEASUREMENT tab)
            if app.RUNButton2.Value == 1
                app.DropDownSTART.Enable = 'off';
                app.DropDownSTOP.Enable = 'off';
            else
                app.DropDownSTART.Enable = 'on';
                app.DropDownSTOP.Enable = 'on';
            end
            
            % Disconnect button
            if (app.connected == 1) && ( (app.RUNButton2.Value == 1) || (app.RUNButton.Value == 1) || (allDisabled == 0) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1) )
                app.ConnectButton.Enable = 'off';
            else
                app.ConnectButton.Enable = 'on';
            end
            
            % Temperature gauge
            if app.connected == 0
                app.tempGauge.Value = 0;
                app.tempLabel.Text = 'N/A';
                app.tempLabel.FontColor = 'k';
            end
            
            % Benchmark Run buttons (SPEEDTEST tab)
            if (app.connected == 0) || (app.RUNButton.Value == 1) || (allDisabled == 0) || (app.RUNButton2.Value == 1) || (app.Benchmark1Button.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.Benchmark1Button.Enable = 'off';
                app.Benchmark2Button.Enable = 'off';
                
            else
                app.Benchmark1Button.Enable = 'on';
                app.Benchmark2Button.Enable = 'on';
            end
            
        end
        
        function MeasurementPanelRefresh(app) % refresh Measurement tab display
            
            plotvector = zeros(1,app.Nmeasurements(app.startTDC+1,app.stopTDC+1));
            for i = 1 : app.Nmeasurements(app.startTDC+1,app.stopTDC+1) % reconstruct vector for plotting data
                plotvector(i) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
            end
            histogram(app.UIAxes2,plotvector,200,'Facecolor',[0.5 0 0.75],'EdgeColor','none'); % plot histogram of measurements (200 bins)
            app.CountsMLabel.Text = strcat( 'Counts:',{' '},int2str(app.Nmeasurements(app.startTDC+1,app.stopTDC+1)) ); % counts label
            text = sprintf( 'Offset: %d ps',round(app.offset(app.startTDC+1,app.stopTDC+1)));
            app.OffsetLabel.Text = text; % offset label
        end
        
        function CalibrationPanelRefresh(app) % refresh Calibration tab display
            
            if app.TDCenabled(app.selectedTDC+1) % determine if the channel is enabled
                
                app.EnableButton.Value = 1; % update enable button & lamp
                app.EnableButton.Text = 'Disable';
                app.EnableLamp.Color = 'g';
                
            else % channel disabled
                
                app.EnableButton.Value = 0;
                app.EnableButton.Text = 'Enable';
                app.EnableLamp.Color = [0.5 0.5 0.5]; % grey
            end
            
            bar(app.UIAxes, app.histogramData(app.selectedTDC+1,:), 'Facecolor',[0.5 0 0.75]); % plot histogram (bar graph) for the selected TDC
            app.CountsLabel.Text = strcat( 'Total counts:',{' '},int2str(app.counts(app.selectedTDC+1)) ); % display hit count for the channel
            
            if app.calibrationPts(app.selectedTDC+1) > 0 % display calibration info
                text = sprintf( 'Calibrated with %d counts.\nActive taps = %d. Avg width = %.2f ps.', ...
                app.calibrationPts(app.selectedTDC+1),app.activeTaps(app.selectedTDC+1),app.avgWidth(app.selectedTDC+1) );
            else
                text = 'This channel has not been calibrated yet.';
            end
            app.CalibrationInfoLabel.Text = text;
        end
        
        function timerCallback(app) % timer-triggered function (for e.g. SoC monitoring)
            
            if (app.RUNButton2.Value == 1) || (app.RUNButton.Value == 1) || (app.Benchmark2Button.Value == 1)
                app.timerFlag = 1; % only set the flag
            else
                readXADC(app); % execute temp measurement NOW
            end
        end
        
        function readXADC(app) % read SoC temperature
            
            if app.connected == 0
                return
            end
            write(app.client, uint16(bitor(bitshift(5,8),0)) ); % send command 5: read XADC
            rawTemp = read(app.client,1,'int32'); % read the 32-bit raw value
            temp = double(rawTemp)*503.975/4096 - 273.15; % convert to °C
            
            % display temperature
            app.tempGauge.Value = temp;
            label = sprintf('%.1f °C',temp);
            app.tempLabel.Text = label;
            if temp >= 80
                app.tempLabel.FontColor = 'r';
            else
                app.tempLabel.FontColor = 'k';
            end
        end
        
        function benchmarkTimerCallback1(app) % timer-triggered benchmark 1 stop
            
            write(app.client, uint16(bitor(bitshift(8,8),0))); % stop speedtest
            Nspeed = read(app.client,1,'uint64'); % number of read stamps
            app.Benchmark1Label.Text = sprintf( 'Read speed: %d Timestamps/Second', round(Nspeed/10)); % print result
            app.Benchmark1Button.Value = 0; % reset button
            %app.Benchmark1Button.Enable = 'on'; % re-enable button
            start(app.xadcTimer) % restart xadc timer
            activeFields(app);
        end
        
        function benchmarkTimerCallback2(app) % timer-triggered benchmark 2 stop
            
            write(app.client, uint16(bitor(bitshift(0,8),0))); % stop channel 0
            app.Benchmark2Button.Value = 0; % reset button
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % initialize some properties values
            
            app.TDCenabled = zeros(1,app.Nch);
        
            app.counts = zeros(1,app.Nch);
            app.histogramData = zeros(app.Nch,app.Nbins);
        
            app.calibrationPts = zeros(1,app.Nch);
            app.binWidths = ones(app.Nch,app.Nbins) * app.T/app.Nbins;
            app.calibrationTable = zeros(app.Nch,app.Nbins);
            
            % generate default calibration table
            for bin = 1 : app.Nbins
                app.calibrationTable(:,bin) = (2*bin-1)/2 * app.T/app.Nbins; % (2N-1)/2 * avg binWidth
            end
            
            app.activeTaps = ones(1,app.Nch) * app.Nbins;
            app.avgWidth = ones(1,app.Nch) * app.T/app.Nbins;
            
            app.DNL = zeros(app.Nch,app.Nbins);
            app.INL = zeros(app.Nch,app.Nbins);
            
            app.TDCmeasurements = zeros(app.Nch,app.Nch,500*app.BRAMsize);
            app.Nmeasurements = zeros(app.Nch,app.Nch);
            app.offset = zeros(app.Nch,app.Nch);
            
            app.windowLow = -3000; % default window (maximum range)
            app.windowHigh = 48000000000;
            
            app.StatsLabel.Visible = 'off'; % stats label invisible
            activeFields(app);
            
            % setup XADC timer
            app.xadcTimer = timer('StartDelay',0, 'Period',1, 'ExecutionMode','fixedSpacing', 'TasksToExecute',Inf); % repeated execution every 1 second
            app.xadcTimer.TimerFcn = @(~,~) timerCallback(app); % TimerFcn callback handle
            
            % setup benchmark timers
            app.benchmarkTimer1 = timer('StartDelay',10, 'ExecutionMode','singleShot'); % execute only once after 10 seconds
            app.benchmarkTimer1.TimerFcn = @(~,~) benchmarkTimerCallback1(app); % TimerFcn callback handle
            app.benchmarkTimer2 = timer('StartDelay',10, 'ExecutionMode','singleShot'); % execute only once after 10 seconds
            app.benchmarkTimer2.TimerFcn = @(~,~) benchmarkTimerCallback2(app); % TimerFcn callback handle
            
            app.fileIndex = 1;
            
        end

        % Value changed function: Benchmark1Button
        function Benchmark1ButtonValueChanged(app, event)
            value = app.Benchmark1Button.Value;
            
            if value == 1 % button pressed
                activeFields(app);
                %app.Benchmark1Button.Enable = 'off'; % disable button
                stop(app.xadcTimer) % stop xadc timer
                start(app.benchmarkTimer1) % start benchmark timer
                write(app.client, uint16(bitor(bitshift(7,8),0))); % start speedtest
            end
            
        end

        % Value changed function: Benchmark2Button
        function Benchmark2ButtonValueChanged(app, event)
            
            if app.Benchmark2Button.Value == 1 % button pressed
                activeFields(app);
                %app.Benchmark2Button.Enable = 'off'; % disable button
                stop(app.xadcTimer) % stop xadc timer
                start(app.benchmarkTimer2) % start benchmark timer
                write(app.client, uint16(bitor(bitshift(1,8),0))); % start channel 0
                
                Nspeed = 0;
                while app.Benchmark2Button.Value == 1 % test loop: download as many timestamps as possible
                    
                    write(app.client, uint16(bitor(bitshift(2,8),0))); % read channel 0
                    Nstamps = read(app.client,1,'uint64'); % how many stamps are arriving
            
                    if Nstamps > 0 % stamps available
                        data = read(app.client,Nstamps,'uint64'); % collect received timestamps
                        Nspeed = Nspeed + Nstamps;
                    end
                    
                    drawnow; % enable loop interruption by other callbacks
                
                    if app.timerFlag % synchronous XADC read
                        readXADC(app);
                        app.timerFlag = 0;
                    end
                end
                % end of test
                app.Benchmark2Label.Text = sprintf( 'Download speed: %d Timestamps/Second', round(Nspeed/10)); % print result
                %app.Benchmark2Button.Enable = 'on'; % re-enable button
                start(app.xadcTimer) % restart xadc timer
                activeFields(app);
            end
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            
            if app.counts(app.selectedTDC+1) >= 10000
                
                % open file for writing calibration data
                filename = ['calTDC' num2str(app.selectedTDC) '.txt'];
                fileID = fopen([app.fileDirectory '\' filename],'w');
                if fileID < 0
                    uialert(app.GUI,'Error writing to calibration file.','Error');
                    return
                end
                
                app.calibrationPts(app.selectedTDC+1) = app.counts(app.selectedTDC+1); 
                fprintf(fileID,'Total counts\t%d\n',app.calibrationPts(app.selectedTDC+1));
                
                for bin = app.Nbins: -1 : 1 % decreasing from top
                    if app.histogramData(app.selectedTDC+1,bin) > 0 % the highest active tap
                        app.activeTaps(app.selectedTDC+1) = bin;
                        app.avgWidth(app.selectedTDC+1) = app.T/bin;
                        break
                    end
                end
                fprintf(fileID,'Active taps\t%d\n',app.activeTaps(app.selectedTDC+1));
                
                fprintf(fileID,'Bin\tCounts\tBin width [ps]\tTime [ps]\tDNL [LSB]\tINL [LSB]\n');
                for bin = 1 : app.Nbins % for all bins
                
                    binWidth = app.histogramData(app.selectedTDC+1,bin)/app.calibrationPts(app.selectedTDC+1) * app.T; % get bin width in ps
                    app.binWidths(app.selectedTDC+1,bin) = binWidth;
                    
                    if bin <= app.activeTaps(app.selectedTDC+1) % DNL
                        % formula from Favi
                        binDNL = app.histogramData(app.selectedTDC+1,bin)/app.calibrationPts(app.selectedTDC+1) * app.activeTaps(app.selectedTDC+1) - 1;
                    else
                        binDNL = 0;
                    end
                    app.DNL(app.selectedTDC+1,bin) = binDNL;
                    
                    if bin == 1
                        % first element
                        app.calibrationTable(app.selectedTDC+1,bin) = binWidth/2;
                        app.INL(app.selectedTDC+1,bin) = binDNL;
                    else
                        % others : cumulative formula from Kintex7_TDC_1 (Liu)
                        app.calibrationTable(app.selectedTDC+1,bin) = app.calibrationTable(app.selectedTDC+1,bin-1) + app.binWidths(app.selectedTDC+1,bin-1)/2 + binWidth/2;
                        % INL formula from Favi
                        app.INL(app.selectedTDC+1,bin) = app.INL(app.selectedTDC+1,bin-1) + binDNL;
                    end
                    
                    % write to file
                    fprintf(fileID,'%d\t%d\t%.2f\t%.2f\t%.4f\t%.3f\n', bin, app.histogramData(app.selectedTDC+1,bin), binWidth, app.calibrationTable(app.selectedTDC+1,bin), binDNL, app.INL(app.selectedTDC+1,bin));
                end
                
                fclose(fileID);
                
                CalibrationPanelRefresh(app); % refresh calibration info
                
            else
                uialert(app.GUI,'Collect at least 10000 points.','Warning','Icon','warning');
            end
            
        end

        % Selection changed function: ChannelselectionButtonGroup
        function ChannelselectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelselectionButtonGroup.SelectedObject;
            
            switch selectedButton % determine selected TDC ID
                    
                case app.TDC0Button
                    app.selectedTDC = 0;
                    app.TDCLabel.Text = 'TDC 0';
                        
                case app.TDC1Button
                    app.selectedTDC = 1;
                    app.TDCLabel.Text = 'TDC 1';
            end
            
            CalibrationPanelRefresh(app); % switch view
            
        end

        % Button pushed function: ClearButton2
        function ClearButton2Pushed(app, event)
            % clear measurements, but not the offset
            
            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,:) = 0;
            app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = 0;
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            % clear on-chip BRAM
            command = uint16(bitor(bitshift(3,8),app.selectedTDC)); % request manual BRAM clear
            write(app.client, command);
            
            % clear accumulated counts
            app.histogramData(app.selectedTDC+1,:) = 0; % overwrite the selected row with zeros
            app.counts(app.selectedTDC+1) = 0;
            
            % refresh display
            CalibrationPanelRefresh(app);
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if app.connected == 0 % try to connect
                
                app.ConnectButton.Text = 'Connecting ...';
                try
                    app.client = tcpclient(app.RPaddr.Value,app.Port.Value,'Timeout',1,'ConnectTimeout',5); % wait for max 5 seconds
                    app.connected = 1; % connection established
                    app.ConnectButton.Text = 'Disconnect';
                    app.ConnectButton.BackgroundColor = 'r';
                    start(app.xadcTimer) % start monitoring timer
                    
                catch err % failed
                    switch err.identifier
                        case 'MATLAB:networklib:tcpclient:cannotCreateObject'
                            uialert(app.GUI,'Connection timeout!','Error');                      
                    end
                    app.ConnectButton.Text = 'Connect';
                    
                end
            else % disconnect
                
                delete (app.client); % 'clear' the client object/property
                app.connected = 0;
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = 'c';
                stop(app.xadcTimer) % stop monitoring timer
            end
            
            activeFields(app);
        end

        % Value changed function: DropDownSTART
        function DropDownSTARTValueChanged(app, event)
            value = app.DropDownSTART.Value;
            
            switch value
                case 'TDC 0'
                    app.startTDC = 0;
                case 'TDC 1'
                    app.startTDC = 1;
            end
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
            activeFields(app);
        end

        % Value changed function: DropDownSTOP
        function DropDownSTOPValueChanged(app, event)
            value = app.DropDownSTOP.Value;
            
            switch value
                case 'TDC 0'
                    app.stopTDC = 0;
                case 'TDC 1'
                    app.stopTDC = 1;
            end
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
            activeFields(app);
        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            value = app.EnableButton.Value;
            
            if value == 1 % enable channel
                
                app.TDCenabled(app.selectedTDC+1) = 1;
                app.EnableButton.Text = 'Disable';
                app.EnableLamp.Color = 'g';
                command = uint16(bitor(bitshift(1,8),app.selectedTDC));
                
            else % disable channel
                
                app.TDCenabled(app.selectedTDC+1) = 0;
                app.EnableButton.Text = 'Enable';
                app.EnableLamp.Color = [0.5 0.5 0.5];
                command = uint16(bitor(bitshift(0,8),app.selectedTDC));
            end
            
            write(app.client, command); % send command to hardware
            %reply = read(app.client,1,'uint64'); % wait for confirmation
            activeFields(app);
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Write histogram to txt file
            filename = ['histogram' num2str(app.fileIndex) '.txt'];
            
            fileID = fopen([app.fileDirectory '\' filename],'w');
            if fileID < 0
                uialert(app.GUI,'Error opening file.','Error');
                return
            end
            
            datavector = zeros(1,app.Nmeasurements(app.startTDC+1,app.stopTDC+1));
            for i = 1 : app.Nmeasurements(app.startTDC+1,app.stopTDC+1) % reconstruct vector of timestamps
                datavector(i) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
            end
            
            [bincounts, edges] = histcounts(datavector,200); % sort into 200 bins
            % bincounts: number of hits in each bin (200)
            % edges: left edges of bins + right edge of last bin (201)
            fprintf(fileID,'Time [ps]\tCounts\n');
            for i = 1 : length(bincounts) % write 200 rows to file
                fprintf(fileID,'%.3f\t%d\n',edges(i),bincounts(i));
            end
            
            fclose(fileID);
            uialert(app.GUI,['Wrote to file ' filename],'Success','Icon','success');
            app.fileIndex = app.fileIndex + 1;
            
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            % save histogramData & counts to a .mat variable file
            
            histogramDataVar = app.histogramData;
            countsVar = app.counts;
            try
                save([app.fileDirectory '\calibrationData.mat'],'histogramDataVar','countsVar');
                uialert(app.GUI,'Saved histogram data.','Success','Icon','success');
            catch
                uialert(app.GUI,'Data could not be saved.','Error','Icon','error');
            end
        end

        % Close request function: GUI
        function GUICloseRequest(app, event)
            delete(app.xadcTimer);
            delete(app.benchmarkTimer1);
            delete(app.benchmarkTimer2);
            delete(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % import histogramData & counts from a .mat variable file
            
            try
                load([app.fileDirectory '\calibrationData.mat']);
            catch
                uialert(app.GUI,'Data not found.','Error','Icon','error');
                return
            end
            
            app.histogramData = histogramDataVar;
            app.counts = countsVar;
            
            CalibrationPanelRefresh(app); % plot histogram and counts
            uialert(app.GUI,'Successfully imported data.','Success','Icon','success');
        end

        % Button pushed function: PeakstatisticsButton
        function PeakstatisticsButtonPushed(app, event)
            
            n = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
            if n == 0
                return
            end
            
            % get peak average
            avg = 0;
            for i = 1 : n
                avg = avg + app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
            end
            avg = avg / n;
            
            % get stdev
            sigma = 0;
            for i = 1 : n
                sigma = sigma + (app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i) - avg)^2 ;
            end
            sigma = sqrt(sigma/n);
            
            text = sprintf( 'Mean = %d ps\nStdev = %d ps', round(avg), round(sigma) ); % print stats label
            app.StatsLabel.Text = text;
            app.StatsLabel.Visible = 'on';
        end

        % Button pushed function: PlotcalibrationdataButton
        function PlotcalibrationdataButtonPushed(app, event)
            
            % plot histogram of bin widths
            figure
            bar(app.binWidths(app.selectedTDC+1,:));
            title('Bin width')
            xlabel('Tap')
            ylabel('ps')
            
            % plot the calibration table line + reference ideal line
            figure
            plot(app.calibrationTable(app.selectedTDC+1,:),'Linewidth',1.5);
            idealLine = refline( app.avgWidth(app.selectedTDC+1), -app.avgWidth(app.selectedTDC+1)/2 );
            idealLine.Color = [0.75 0.75 0.75];
            title('Calibration table')
            xlabel('Tap')
            ylabel('Measured time [ps]')
            ylim([0 inf])
            
            % plot DNL & INL
            figure
            plot(app.DNL(app.selectedTDC+1,:),'Linewidth',1.5);
            hold on
            plot(app.INL(app.selectedTDC+1,:),'Linewidth',1.5);
            hold off
            title('Nonlinearity')
            xlabel('Tap')
            ylabel('LSB')
            grid on
            legend('DNL','INL')
            
        end

        % Value changed function: RUNButton2
        function RUNButton2ValueChanged(app, event)
            
            if app.RUNButton2.Value == 1 % RUN measurement
                if app.Nmeasurements(app.startTDC+1,app.stopTDC+1) < length(app.TDCmeasurements)
                    app.RUNButton2.Text = 'STOP';
                    app.RUNButton2.BackgroundColor = 'r';
                
                    if app.startTDC ~= app.stopTDC % START - STOP pair measurement
                        write(app.client, uint16(bitor(bitshift(3,8),app.startTDC)) ); % first clear both channels
                        write(app.client, uint16(bitor(bitshift(3,8),app.stopTDC)) );
                        write(app.client, uint16(bitor(bitshift(4,8),app.startTDC)) ); % RUN in tandem
                        write(app.client, uint16(bitor(bitshift(4,8),app.stopTDC)) );
                    
                    else % single TDC measurement
                        write(app.client, uint16(bitor(bitshift(3,8),app.startTDC)) ); % clear channel
                        write(app.client, uint16(bitor(bitshift(1,8),app.startTDC)) ); % RUN
                    end
                else % memory full
                    uialert(app.GUI,'Buffer full!','Info','Icon','info');
                    app.RUNButton2.Value = 0;
                    return % exit callback
                end
                
            else % STOP measurement
                app.RUNButton2.Text = 'RUN';
                app.RUNButton2.BackgroundColor = [0.30 0.75 0.93];
                
                if app.startTDC ~= app.stopTDC % pair
                    write(app.client, uint16(bitor(bitshift(6,8),app.startTDC)) ); % stop and break up both channels
                else % single
                    write(app.client, uint16(bitor(bitshift(0,8),app.startTDC)) ); % stop channel
                end
            end
            
            activeFields(app);
            
            triggerBuffer = uint64(zeros(1,app.BRAMsize));
            Nstart = 0; % No. of hits in start TDC
            Nstop = 0; % No. of hits in stop TDC
            firstSTOPtriggerIndex = 0; % trigger index of last firstSTOP
            
            last = uint64(0); % last timestamp from single TDC measurement
            
            while app.RUNButton2.Value == 1 % synced measurement loop
                
                if app.startTDC ~= app.stopTDC % 2-channel START-STOP time interval measurement
                    
                    % read stopTDC (events)
                    write(app.client, uint16(bitor(bitshift(2,8),app.stopTDC)) );
                    Nevents = read(app.client,1,'uint64'); % how many stop stamps are arriving
                    if Nevents > 0
                        events = read(app.client,Nevents,'uint64'); % collect received timestamps
                        Nstop = Nstop + Nevents;
                    end
                    
                    % read startTDC (triggers)
                    write(app.client, uint16(bitor(bitshift(2,8),app.startTDC)) );
                    Ntrigs = read(app.client,1,'uint64'); % how many trigger stamps are arriving
                    if Ntrigs > 0
                        triggers = read(app.client,Ntrigs,'uint64'); % collect received timestamps
                        for i = 1 : length(triggers) % append triggers to triggerBuffer
                            addr = bitand ( bitshift(triggers(i),-48), hex2dec('ffff') ); % BRAM address
                            triggerBuffer(addr+1) = triggers(i);
                        end
                        Nstart = Nstart + Ntrigs;
                    end
                    
                    % process events
                    for i = 1 : Nevents
                        trigger = bitand ( bitshift(events(i),-32), hex2dec('ffff') ); % trigger index for this event
                        if trigger == 0
                            continue % trigger 0 is invalid
                        end
                        if (trigger == firstSTOPtriggerIndex) && (app.FirstSTOPCheckBox.Value == 1)
                            continue % skip STOP stamps that are not first
                        end
                        
                        coarseSTART = int32(bitand ( bitshift(triggerBuffer(trigger),-8), hex2dec('ffffff') )); % 24-bit coarseSTART
                        coarseSTOP = int32(bitand ( bitshift(events(i),-8), hex2dec('ffffff') )); % 24-bit coarseSTOP
                        delta = bitand ( coarseSTOP-coarseSTART, hex2dec('ffffff') ); % 24-bit coarse difference, no overflow
                                     
                        coarseTI = double(delta) * app.T; % coarse count difference to ps
                        thermo = bitand( triggerBuffer(trigger), hex2dec('ff') ); % lowest 8 bits of start
                        fineSTART = app.calibrationTable(app.startTDC+1,thermo); % convert thermocode to ps
                        thermo = bitand( events(i), hex2dec('ff') ); % same for stop
                        fineSTOP = app.calibrationTable(app.stopTDC+1,thermo);
                        TI = coarseTI - (fineSTOP - fineSTART) - app.offset(app.startTDC+1,app.stopTDC+1); % measured time interval
                        
                        if (trigger ~= firstSTOPtriggerIndex) && (TI > 0) % firstSTOP identification
                            firstSTOPtriggerIndex = trigger;
                        end
                        
                        if (app.windowLow <= TI) && (TI <= app.windowHigh) % valid time interval
                            
                            memoryIndex = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % location of the next point to be written
                            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,memoryIndex) = TI; % save point in TDCmeasurements
                            app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % increment N
                            
                            if app.Nmeasurements(app.startTDC+1,app.stopTDC+1) == length(app.TDCmeasurements) % memory full
                                app.RUNButton2.Value = 0;
                                app.RUNButton2.Text = 'RUN';
                                app.RUNButton2.BackgroundColor = [0.30 0.75 0.93];
                                write(app.client, uint16(bitor(bitshift(6,8),app.startTDC)) ); % stop both channels
                                activeFields(app);
                                break % exit loop
                            end 
                        end
                    end
                    
                    % TDCs are clearing, reset buffer
                    if (Nstart == app.BRAMsize) || (Nstop == app.BRAMsize)
                        Nstart = 0;
                        Nstop = 0;
                        triggerBuffer(1,:) = 0;
                        firstSTOPtriggerIndex = 0;
                    end   
                    
                else % single TDC measurement -> time intervals between adjacent timestamps
                    
                    % read TDC
                    write(app.client, uint16(bitor(bitshift(2,8),app.stopTDC)) );
                    Nevents = read(app.client,1,'uint64'); % how many timestamps are arriving
                    if Nevents > 0
                        events = read(app.client,Nevents,'uint64'); % collect received timestamps
                        
                        % process events
                        for i = 1 : Nevents 
                            if i == 1 % the START timestamp is the last one from previous set
                                if last ~= 0
                                    coarseSTART = int32(bitand ( bitshift(last,-8), hex2dec('ffffff') )); % 24-bit coarseSTART  
                                    thermo = bitand( last, hex2dec('ff') ); % lowest 8 bits of start
                                    fineSTART = app.calibrationTable(app.stopTDC+1,thermo); % convert thermocode to ps
                                else
                                    continue % fist stamp in TDC -> invalid
                                end
                            else % the START timestamp is one index behind
                                coarseSTART = int32(bitand ( bitshift(events(i-1),-8), hex2dec('ffffff') )); % 24-bit coarseSTART  
                                thermo = bitand( events(i-1), hex2dec('ff') ); % lowest 8 bits of start
                                fineSTART = app.calibrationTable(app.stopTDC+1,thermo); % convert thermocode to ps
                            end
                            coarseSTOP = int32(bitand ( bitshift(events(i),-8), hex2dec('ffffff') )); % 24-bit coarseSTOP
                            delta = bitand ( coarseSTOP-coarseSTART, hex2dec('ffffff') ); % 24-bit coarse difference, no overflow
                                
                            coarseTI = double(delta) * app.T; % coarse count difference to ps
                            thermo = bitand( events(i), hex2dec('ff') ); % lowest 8 bits of stop
                            fineSTOP = app.calibrationTable(app.stopTDC+1,thermo); % thermocode to ps
                            TI = coarseTI - (fineSTOP - fineSTART); % measured time interval
                            
                            if (app.windowLow <= TI) && (TI <= app.windowHigh) % valid time interval
                                
                                memoryIndex = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % location of the next point to be written
                                app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,memoryIndex) = TI; % save point in TDCmeasurements
                                app.Nmeasurements(app.startTDC+1,app.stopTDC+1) = app.Nmeasurements(app.startTDC+1,app.stopTDC+1) + 1; % increment N
                                
                                if app.Nmeasurements(app.startTDC+1,app.stopTDC+1) == length(app.TDCmeasurements) % memory full
                                    app.RUNButton2.Value = 0;
                                    app.RUNButton2.Text = 'RUN';
                                    app.RUNButton2.BackgroundColor = [0.30 0.75 0.93];
                                    write(app.client, uint16(bitor(bitshift(0,8),app.startTDC)) ); % stop channel
                                    activeFields(app);
                                    break % exit loop
                                end
                            end
                        end
                        last = events(i); % last timestamp in this set
                        Nstop = Nstop + Nevents;
                    end
                    
                    % TDC is clearing, reset last timestamp
                    if Nstop == app.BRAMsize
                        Nstop = 0;
                        last = 0;
                    end
                end
                
                MeasurementPanelRefresh(app); % draw histogram & stuff
                drawnow; % enable loop interruption by other callbacks
                
                if app.timerFlag % synchronous XADC read
                    readXADC(app);
                    app.timerFlag = 0;
                end
            end
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
      
            if app.RUNButton.Value == 1
                app.RUNButton.Text = 'STOP';
                app.RUNButton.BackgroundColor = 'r';
            else
                app.RUNButton.Text = 'RUN';
                app.RUNButton.BackgroundColor = 'g';
            end
            activeFields(app);
            
            while app.RUNButton.Value == 1 % read TDC data loop
                
                for TDCindex = 0:app.Nch-1 % scroll through all channels
                    
                    if app.TDCenabled(TDCindex+1) % for enabled channels
                        
                        command = uint16(bitor(bitshift(2,8),TDCindex)); % request to read the selected channel
                        write(app.client, command);
                        Nstamps = read(app.client,1,'uint64'); % how many stamps are arriving
            
                        if Nstamps > 0 % stamps available
                            data = read(app.client,Nstamps,'uint64'); % collect received timestamps
                            for timestamp = 1 : length(data)
                                %half = typecast(data(timestamp),'uint32'); % timestamp split in 32bit halves   
                                %fprintf( '%s %s\n', dec2hex(half(2),8), dec2hex(half(1),8) ); % display timestamps
                                thermocode = bitand( data(timestamp), 255); % lowest 8 bits
                                app.histogramData(TDCindex+1, thermocode) = app.histogramData(TDCindex+1, thermocode) + 1; % increment the correspoding bin
                            end
                            app.counts(TDCindex+1) = app.counts(TDCindex+1) + Nstamps; % increase number of total counts
                        end
                    end
                end
                
                CalibrationPanelRefresh(app); % plot histogram and counts
                drawnow; % enable loop interruption by other callbacks
                
                if app.timerFlag % synchronous XADC read
                    readXADC(app);
                    app.timerFlag = 0;
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            n = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
            % shift data back to original
            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) + app.offset(app.startTDC+1,app.stopTDC+1);
            app.offset(app.startTDC+1,app.stopTDC+1) = 0; % reset offset
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
        end

        % Value changed function: ScaleSwitch
        function ScaleSwitchValueChanged(app, event)
            
            if app.ScaleSwitch.Value == 'Log' % histogram Y Scale
                set(app.UIAxes2,'YScale','log');
            else
                set(app.UIAxes2,'YScale','lin');
            end
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            
            n = app.Nmeasurements(app.startTDC+1,app.stopTDC+1);
            if (n == 0) || (app.startTDC == app.stopTDC)
                return
            end
            
            % get peak average
            avg = 0;
            for i = 1 : n
                avg = avg + app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,i);
            end
            avg = avg / n;
            
            % shift data
            app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) = app.TDCmeasurements(app.startTDC+1,app.stopTDC+1,1:n) - avg;
            app.offset(app.startTDC+1,app.stopTDC+1) = app.offset(app.startTDC+1,app.stopTDC+1) + avg;
            
            MeasurementPanelRefresh(app);
            app.StatsLabel.Visible = 'off';
        end

        % Value changed function: windowHighField
        function windowHighFieldValueChanged(app, event)
            value = app.windowHighField.Value;
            app.windowHigh = value;
        end

        % Value changed function: windowLowField
        function windowLowFieldValueChanged(app, event)
            value = app.windowLowField.Value;
            app.windowLow = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure;
            app.GUI.Name = 'Red Pitaya TDC - by Michel Adamič';
            app.GUI.CloseRequestFcn = createCallbackFcn(app, @GUICloseRequest, true);
            app.RedPitayaIPAddressLabel = uilabel(app.GUI);
            app.RedPitayaIPAddressLabel.Text = 'Red Pitaya IP Address:';
            app.RPaddr = uieditfield(app.GUI, 'text');
            app.RPaddr.Value = '192.168.1.15';
            app.PortEditFieldLabel = uilabel(app.GUI);
            app.PortEditFieldLabel.Text = 'Port:';
            app.Port = uieditfield(app.GUI, 'numeric');
            app.Port.Value = 1001;
            app.ConnectButton = uibutton(app.GUI, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.TabGroup = uitabgroup(app.GUI);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.ChannelselectionButtonGroup = uibuttongroup(app.CalibrationTab);
            app.ChannelselectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelselectionButtonGroupSelectionChanged, true);
            app.ChannelselectionButtonGroup.Title = 'Channel selection';
            app.TDC0Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.TDC0Button.Text = 'TDC 0';
            app.TDC0Button.Value = true;
            app.TDC1Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.TDC1Button.Text = 'TDC 1';
            app.RUNButton = uibutton(app.CalibrationTab, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Text = 'RUN';
            app.TDCLabel = uilabel(app.CalibrationTab);
            app.TDCLabel.Text = 'TDC 0';
            app.CalibrationInfoLabel = uilabel(app.CalibrationTab);
            app.CalibrationInfoLabel.Text = 'This channel has not been calibrated yet.';
            app.EnableButton = uibutton(app.CalibrationTab, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            app.EnableLamp = uilamp(app.CalibrationTab);
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ClearButton = uibutton(app.CalibrationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CountsLabel = uilabel(app.CalibrationTab);
            app.CountsLabel.Text = 'Total counts: 0';
            app.UIAxes = uiaxes(app.CalibrationTab);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'Bin')
            ylabel(app.UIAxes, 'Counts')
            app.UIAxes.XLim = [0 192];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.TickDirMode = 'manual';
            app.PlotcalibrationdataButton = uibutton(app.CalibrationTab, 'push');
            app.PlotcalibrationdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcalibrationdataButtonPushed, true);
            app.PlotcalibrationdataButton.Text = 'Plot calibration data';
            app.ImportButton = uibutton(app.CalibrationTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton_2 = uibutton(app.CalibrationTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.HistogramdataLabel = uilabel(app.CalibrationTab);
            app.HistogramdataLabel.Text = 'Histogram data';
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.UIAxes2 = uiaxes(app.MeasurementTab);
            title(app.UIAxes2, 'TDC Measurement')
            xlabel(app.UIAxes2, 'Time [ps]')
            ylabel(app.UIAxes2, 'Counts')
            app.DropDownSTART = uidropdown(app.MeasurementTab);
            app.DropDownSTART.Items = {'TDC 0', 'TDC 1'};
            app.DropDownSTART.ValueChangedFcn = createCallbackFcn(app, @DropDownSTARTValueChanged, true);
            app.DropDownSTART.Value = 'TDC 0';
            app.STARTLabel = uilabel(app.MeasurementTab);
            app.STARTLabel.Text = 'START';
            app.STOPLabel = uilabel(app.MeasurementTab);
            app.STOPLabel.Text = 'STOP';
            app.DropDownSTOP = uidropdown(app.MeasurementTab);
            app.DropDownSTOP.Items = {'TDC 0', 'TDC 1'};
            app.DropDownSTOP.ValueChangedFcn = createCallbackFcn(app, @DropDownSTOPValueChanged, true);
            app.DropDownSTOP.Value = 'TDC 0';
            app.RUNButton2 = uibutton(app.MeasurementTab, 'state');
            app.RUNButton2.ValueChangedFcn = createCallbackFcn(app, @RUNButton2ValueChanged, true);
            app.RUNButton2.Text = 'RUN';
            app.CountsMLabel = uilabel(app.MeasurementTab);
            app.CountsMLabel.Text = 'Counts: 0';
            app.OffsetLabel = uilabel(app.MeasurementTab);
            app.OffsetLabel.Text = 'Offset: 0 ps';
            app.ZeroButton = uibutton(app.MeasurementTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.ResetButton = uibutton(app.MeasurementTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PeakstatisticsButton = uibutton(app.MeasurementTab, 'push');
            app.PeakstatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @PeakstatisticsButtonPushed, true);
            app.PeakstatisticsButton.Text = 'Peak statistics';
            app.ClearButton2 = uibutton(app.MeasurementTab, 'push');
            app.ClearButton2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton2Pushed, true);
            app.ClearButton2.Text = 'Clear';
            app.StatsLabel = uilabel(app.MeasurementTab);
            app.StatsLabel.Text = 'Stats';
            app.TriggerwindowLabel = uilabel(app.MeasurementTab);
            app.TriggerwindowLabel.Text = 'Trigger window';
            app.windowLowField = uieditfield(app.MeasurementTab, 'numeric');
            app.windowLowField.ValueChangedFcn = createCallbackFcn(app, @windowLowFieldValueChanged, true);
            app.windowLowField.Limits = [-3000 48000000000];
            app.windowLowField.Value = -3000;
            app.ScaleSwitch = uiswitch(app.MeasurementTab, 'slider');
            app.ScaleSwitch.Items = {'Lin', 'Log'};
            app.ScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @ScaleSwitchValueChanged, true);
            app.ScaleSwitch.Value = 'Lin';
            app.ExportButton = uibutton(app.MeasurementTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FromLabel = uilabel(app.MeasurementTab);
            app.FromLabel.Text = 'From';
            app.psLabel = uilabel(app.MeasurementTab);
            app.psLabel.Text = 'ps';
            app.ToEditFieldLabel = uilabel(app.MeasurementTab);
            app.ToEditFieldLabel.Text = 'To';
            app.windowHighField = uieditfield(app.MeasurementTab, 'numeric');
            app.windowHighField.ValueChangedFcn = createCallbackFcn(app, @windowHighFieldValueChanged, true);
            app.windowHighField.Limits = [-3000 48000000000];
            app.windowHighField.Value = 48000000000;
            app.psLabel_2 = uilabel(app.MeasurementTab);
            app.psLabel_2.Text = 'ps';
            app.FirstSTOPCheckBox = uicheckbox(app.MeasurementTab);
            app.FirstSTOPCheckBox.Text = '1st STOP';
            app.SpeedtestTab = uitab(app.TabGroup);
            app.SpeedtestTab.Title = 'Speedtest';
            app.TimestampcollectionbenchmarkLabel = uilabel(app.SpeedtestTab);
            app.TimestampcollectionbenchmarkLabel.Text = 'Timestamp collection benchmark';
            app.ULabel = uilabel(app.SpeedtestTab);
            app.ULabel.Text = 'Uses channel 0 (TDC0) to measure the maximum number of collected timestamps in a 10 second interval.';
            app.ARMCprogramLabel = uilabel(app.SpeedtestTab);
            app.ARMCprogramLabel.Text = 'ARM C program:';
            app.Benchmark1Button = uibutton(app.SpeedtestTab, 'state');
            app.Benchmark1Button.ValueChangedFcn = createCallbackFcn(app, @Benchmark1ButtonValueChanged, true);
            app.Benchmark1Button.Text = 'Run';
            app.Benchmark1Label = uilabel(app.SpeedtestTab);
            app.Benchmark1Label.Text = 'Read speed: 0 Timestamps/Second';
            app.MatlabGUILabel = uilabel(app.SpeedtestTab);
            app.MatlabGUILabel.Text = 'Matlab GUI:';
            app.Benchmark2Button = uibutton(app.SpeedtestTab, 'state');
            app.Benchmark2Button.ValueChangedFcn = createCallbackFcn(app, @Benchmark2ButtonValueChanged, true);
            app.Benchmark2Button.Text = 'Run';
            app.Benchmark2Label = uilabel(app.SpeedtestTab);
            app.Benchmark2Label.Text = 'Download speed: 0 Timestamps/Second';
            app.SystemTempLabel = uilabel(app.GUI);
            app.SystemTempLabel.Text = 'System Temp';
            app.tempGauge = uigauge(app.GUI, 'ninetydegree');
            app.tempGauge.Limits = [0 90];
            app.tempGauge.MajorTicks = [0 30 60 90];
            app.tempLabel = uilabel(app.GUI);
            app.tempLabel.Text = 'N/A';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TDCgui3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end